classdef PrepareDataPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrepareDataUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RestoredefaultvaluesButton     matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        InfoTextLabel                  matlab.ui.control.Label
        FilteragainstDropDown          matlab.ui.control.DropDown
        FilteragainstDropDownLabel     matlab.ui.control.Label
        FilteringmethodDropDown        matlab.ui.control.DropDown
        FilteringmethodDropDownLabel   matlab.ui.control.Label
        FiltertolerancelocalisationsSpinner  matlab.ui.control.Spinner
        FiltertolerancelocalisationsSpinnerLabel  matlab.ui.control.Label
        IllustrationborderpixelsSpinner  matlab.ui.control.Spinner
        IllustrationborderpixelsSpinnerLabel  matlab.ui.control.Label
        MemoryparameterframesSpinner   matlab.ui.control.Spinner
        MemoryparameterframesSpinnerLabel  matlab.ui.control.Label
        MinimumtracklengthframesSpinner  matlab.ui.control.Spinner
        MinimumtracklengthframesSpinnerLabel  matlab.ui.control.Label
        TrackbufferframesSpinner       matlab.ui.control.Spinner
        TrackbufferframesSpinnerLabel  matlab.ui.control.Label
        PixelscalenmpixelSpinner       matlab.ui.control.Spinner
        PixelscalenmpixelSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp;

        px_scale        %pixel scale (nm/pixel)
        track_buffer    %track buffer (frames)
        min_track_len   %minimum track length
        mem_param       %memory parameter
        border          %border around all illustrations (pixels)
        filter_meth     %filtering method
        filter_against  %filter against (localisations or tracks)
        tolerance       %filter tolerance
        cancel          %true if user presses cancel
        ignore_px_scale %true if pixel scale has already been defined; this enables the code to disable the pixel scale input, and not update it or reset values with defaults, etc.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.PrepareDataUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.PrepareDataUIFigure.Position(3)/2));
            app.PrepareDataUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.PrepareDataUIFigure.Position(4)/2));

            %<<search for parameters in loaded config>>
            
            %disable pixel scale input if px_scale is already defined; and set flag ignore_px_scale to enable it to be ignored in callbacks
            app.ignore_px_scale = false;
            if isfield(app.mainapp.movie_data, "params") && isfield(app.mainapp.movie_data.params, "px_scale")
                px_scale = app.mainapp.movie_data.params.px_scale;
                if ~isempty(px_scale) && px_scale ~= 0
                    app.ignore_px_scale = true;
                    app.PixelscalenmpixelSpinner.Value  = px_scale;
                    app.PixelscalenmpixelSpinner.Enable = 'off';
                end
            end
        end

        % Button pushed function: RestoredefaultvaluesButton
        function RestoredefaultvaluesButtonPushed(app, event)
            if ~app.ignore_px_scale
                app.PixelscalenmpixelSpinner.Value          = 96;
            end
            app.TrackbufferframesSpinner.Value              = 3;
            app.MinimumtracklengthframesSpinner.Value       = 50;
            app.MemoryparameterframesSpinner.Value          = 1;
            app.FilteringmethodDropDown.Value               = 'truncate';
            app.FilteragainstDropDown.Value                 = 'tracks';
            app.FiltertolerancelocalisationsSpinner.Value   = 1;
            app.IllustrationborderpixelsSpinner.Value       = 5;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %write values to main GUI params
            
            %obtain parameters from GUI inputs
            app.mainapp.movie_data.params.track_buffer              = app.TrackbufferframesSpinner.Value;               %minimum number of empty frames between tracks in same cell
            app.mainapp.movie_data.params.min_track_len             = app.MinimumtracklengthframesSpinner.Value;        %minimum length of a track (in localisations) to use in analysis
            app.mainapp.movie_data.params.filtering_method          = app.FilteringmethodDropDown.Value;                %filtering method which determines how filterTracks() handles multiple trajectories with temporal overlaps in a cell; this is currently used as a record of the process used to filter, assigned during filterTracks.m
            app.mainapp.movie_data.params.filter_against            = app.FilteragainstDropDown.Value;                  %determine whether to filter basaed on the presence of other tracks or other localisations; this has been superceeded as a record of the filtering process by app.movie_data.params.filtering_method, which is assigned during filterTracks.m
            app.mainapp.movie_data.params.filter_tolerance          = app.FiltertolerancelocalisationsSpinner.Value;    %maximum number of localisations that can coincide with the track before it is filtered when filtering by localisations
            app.mainapp.movie_data.params.mem_param                 = app.MemoryparameterframesSpinner.Value;           %memory parameter used to filter tracks
            app.mainapp.movie_data.params.ill_border                = app.IllustrationborderpixelsSpinner.Value;        %size of border around cells in illustrations (pixels)
            app.mainapp.movie_data.state.cancel                     = false;                                            %user did not press cancel
            %only set px_scale if it is not already defined
            if ~app.ignore_px_scale
                app.mainapp.movie_data.params.px_scale              = app.PixelscalenmpixelSpinner.Value;               %pixel scale (nm/pixel)
            end
            
            %delete this window
            delete(app.PrepareDataUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainapp.movie_data.state.cancel = true;
            delete(app.PrepareDataUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PrepareDataUIFigure = uifigure('Visible', 'off');
            app.PrepareDataUIFigure.Name = 'DeepTRACE: Data Preparation Settings';
            app.PrepareDataUIFigure.Icon = fullfile(pathToMLAPP, 'DeepTRACE logo.png');
            app.GridLayout = uigridlayout(app.PrepareDataUIFigure);
            app.PixelscalenmpixelSpinnerLabel = uilabel(app.GridLayout);
            app.PixelscalenmpixelSpinnerLabel.Text = 'Pixel scale (nm/pixel)';
            app.PixelscalenmpixelSpinner = uispinner(app.GridLayout);
            app.PixelscalenmpixelSpinner.Limits = [0 Inf];
            app.PixelscalenmpixelSpinner.Tooltip = {'The size of each pixel in the video.'; ''; 'Important: the value used here during data preparation will scale the entire downstream analysis, updating this later will not change the pixel scale of existing data.'};
            app.PixelscalenmpixelSpinner.Value = 96;
            app.TrackbufferframesSpinnerLabel = uilabel(app.GridLayout);
            app.TrackbufferframesSpinnerLabel.Text = 'Track buffer (frames)';
            app.TrackbufferframesSpinner = uispinner(app.GridLayout);
            app.TrackbufferframesSpinner.Limits = [0 Inf];
            app.TrackbufferframesSpinner.ValueDisplayFormat = '%.0f';
            app.TrackbufferframesSpinner.Tooltip = {'Minimum number of empty frames between tracks in the same cell.'; ''; 'Trajectories spaced by less than this number will result in a conflict, which is resolved using the settings below.'};
            app.TrackbufferframesSpinner.Value = 3;
            app.MinimumtracklengthframesSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumtracklengthframesSpinnerLabel.Text = 'Minimum track length (frames)';
            app.MinimumtracklengthframesSpinner = uispinner(app.GridLayout);
            app.MinimumtracklengthframesSpinner.Limits = [0 Inf];
            app.MinimumtracklengthframesSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumtracklengthframesSpinner.Tooltip = {'Minimum length of trajectories. Trajectories shorter than this minimum length are filtered in the data preparation stage. Note that following filtering using the ''truncate'' method some trajectories may have shorter durations.'};
            app.MinimumtracklengthframesSpinner.Value = 50;
            app.MemoryparameterframesSpinnerLabel = uilabel(app.GridLayout);
            app.MemoryparameterframesSpinnerLabel.Text = 'Memory parameter (frames)';
            app.MemoryparameterframesSpinner = uispinner(app.GridLayout);
            app.MemoryparameterframesSpinner.Limits = [0 Inf];
            app.MemoryparameterframesSpinner.ValueDisplayFormat = '%.0f';
            app.MemoryparameterframesSpinner.Tooltip = {'The number of consecutive frames that can be missed from a trajectory without filtering.'; ''; 'A memory parameter of 1 is typical in single molecule tracking applications to account for the possilbility of blinking events.'};
            app.MemoryparameterframesSpinner.Value = 1;
            app.IllustrationborderpixelsSpinnerLabel = uilabel(app.GridLayout);
            app.IllustrationborderpixelsSpinnerLabel.Text = 'Illustration border (pixels)';
            app.IllustrationborderpixelsSpinner = uispinner(app.GridLayout);
            app.IllustrationborderpixelsSpinner.Limits = [0 Inf];
            app.IllustrationborderpixelsSpinner.ValueDisplayFormat = '%.0f';
            app.IllustrationborderpixelsSpinner.Tooltip = {'Size of border (in pixels) around each cell''s ROI to use in illustrations. This parameter is provided here in order to pre-process reference image overlays.'};
            app.IllustrationborderpixelsSpinner.Value = 5;
            app.FiltertolerancelocalisationsSpinnerLabel = uilabel(app.GridLayout);
            app.FiltertolerancelocalisationsSpinnerLabel.Text = 'Filter tolerance (localisations)';
            app.FiltertolerancelocalisationsSpinner = uispinner(app.GridLayout);
            app.FiltertolerancelocalisationsSpinner.Limits = [0 Inf];
            app.FiltertolerancelocalisationsSpinner.ValueDisplayFormat = '%.0f';
            app.FiltertolerancelocalisationsSpinner.Tooltip = {'Minimum number of conflicts (defined by the trajectory conflict resolution settings above) before a trajectory is filtered. A higher number here indicates a more tolerate approach to filtering, and may require the user to discard more molecules during the labelling process.'};
            app.FiltertolerancelocalisationsSpinner.Value = 1;
            app.FilteringmethodDropDownLabel = uilabel(app.GridLayout);
            app.FilteringmethodDropDownLabel.Text = 'Filtering method';
            app.FilteringmethodDropDown = uidropdown(app.GridLayout);
            app.FilteringmethodDropDown.Items = {'truncate', 'eliminate', 'length only'};
            app.FilteringmethodDropDown.Tooltip = {'Determines how the software handles temporal overlaps of molecules in the same cell. Options are,'; ''; '''truncate'': eliminates all frames from overlapping regions of tracks to retain only the regions for which a single molecule is present, splitting tracks if necessary.'; ''; '''eliminate'': removes all trajectories that fall within the track buffer of neighbouring trajectories.'; ''; '''length only'': does not apply any filtering resulting from temporal overlaps of multiple molecules in the same cell. This method only removes tracks which do not meet the minimum track length criteria.'};
            app.FilteringmethodDropDown.Value = 'truncate';
            app.FilteragainstDropDownLabel = uilabel(app.GridLayout);
            app.FilteragainstDropDownLabel.Text = 'Filter against';
            app.FilteragainstDropDown = uidropdown(app.GridLayout);
            app.FilteragainstDropDown.Items = {'localisations', 'tracks'};
            app.FilteragainstDropDown.Tooltip = {'Determines whether to perform the filtering process against the list of other existing trajectories in the same cell, or against the full database of localisations, which includes those that did not form trajectories.'; ''; 'Filtering against localisations imposes a stricter criteria for trajectory conflict resolution.'};
            app.FilteragainstDropDown.Value = 'tracks';
            app.InfoTextLabel = uilabel(app.GridLayout);
            app.InfoTextLabel.Text = {'Your data has been imported successfully.'; ''; 'The following options determine how tracks are connected and filtered.'; ''; 'Information about each parameter hover over the input value to display more information or consult the Methods section of the DeepTRACE publication.'};
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RestoredefaultvaluesButton = uibutton(app.GridLayout, 'push');
            app.RestoredefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultvaluesButtonPushed, true);
            app.RestoredefaultvaluesButton.Text = 'Restore default values';
            % Show the figure after all components are created
            app.PrepareDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrepareDataPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrepareDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrepareDataUIFigure)
        end
    end
end