classdef TemporalContextPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        InstructionsforuserLabel  matlab.ui.control.Label
        FramesafterSpinner        matlab.ui.control.Spinner
        FramesafterSpinnerLabel   matlab.ui.control.Label
        FramesbeforeSpinner       matlab.ui.control.Spinner
        FramesbeforeSpinnerLabel  matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        mainapp;   %storing a reference to the main GUI
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, frames_before, frames_after)
            app.FramesbeforeSpinner.Value   = frames_before;
            app.FramesafterSpinner.Value    = frames_after;
            
            %store the handles to the main app
            app.mainapp = mainapp;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.UIFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.UIFigure.Position(3)/2));
            app.UIFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.UIFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainapp.movie_data.state.frames_before = app.FramesbeforeSpinner.Value;
            app.mainapp.movie_data.state.frames_after = app.FramesafterSpinner.Value;
            
            delete(TemporalContextPopUp);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainapp.movie_data.state.frames_before = 0;
            app.mainapp.movie_data.state.frames_after = 0;
            
            delete(TemporalContextPopUp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FramesbeforeSpinnerLabel = uilabel(app.GridLayout);
            app.FramesbeforeSpinnerLabel.Text = 'Frames before';
            app.FramesbeforeSpinner = uispinner(app.GridLayout);
            app.FramesbeforeSpinner.Limits = [0 Inf];
            app.FramesbeforeSpinner.ValueDisplayFormat = '%.0f';
            app.FramesafterSpinnerLabel = uilabel(app.GridLayout);
            app.FramesafterSpinnerLabel.Text = 'Frames after';
            app.FramesafterSpinner = uispinner(app.GridLayout);
            app.FramesafterSpinner.Limits = [0 Inf];
            app.FramesafterSpinner.ValueDisplayFormat = '%.0f';
            app.InstructionsforuserLabel = uilabel(app.GridLayout);
            app.InstructionsforuserLabel.Text = {'Please provide a suitable range of frames before and after each timepoint to generate time shifted step sizes.'; ''; 'This provides temporal context for use with models that do not natively handle this.'; ''; 'Note that temporal timescale for neural networks is applied via the window size in the ML training options.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TemporalContextPopUp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end